classdef exampleApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageEnhancementUIFigure  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        EnterImageTab             matlab.ui.container.Tab
        BrowseButton              matlab.ui.control.Button
        Panel_5                   matlab.ui.container.Panel
        OriginalImage             matlab.ui.control.Image
        Panel_3                   matlab.ui.container.Panel
        SelectanimageandenterfourpointsLabel  matlab.ui.control.Label
        SelecttwopointsfromtheimageButton  matlab.ui.control.Button
        EnchanceImageButton       matlab.ui.control.Button
        Panel_2                   matlab.ui.container.Panel
        x1TextArea                matlab.ui.control.TextArea
        x1TextAreaLabel           matlab.ui.control.Label
        x2TextArea                matlab.ui.control.TextArea
        x2TextAreaLabel           matlab.ui.control.Label
        Panel_4                   matlab.ui.container.Panel
        y2TextArea                matlab.ui.control.TextArea
        y2TextAreaLabel           matlab.ui.control.Label
        y1TextArea                matlab.ui.control.TextArea
        y1TextAreaLabel           matlab.ui.control.Label
        EnhancedImageTab          matlab.ui.container.Tab
        ShiftAmountLabel          matlab.ui.control.Label
        ShiftRightButton          matlab.ui.control.Button
        ShiftLeftButton           matlab.ui.control.Button
        ShiftDownButton           matlab.ui.control.Button
        ShiftUpButton             matlab.ui.control.Button
        ShiftPixelsEditField      matlab.ui.control.NumericEditField
        RotateImageButton         matlab.ui.control.Button
        SaveImageButton           matlab.ui.control.Button
        Panel_6                   matlab.ui.container.Panel
        bicubicImage              matlab.ui.control.Image
        VDSRImage                 matlab.ui.control.Image
        RotatedImageTab           matlab.ui.container.Tab
        WhichimagewouldyouliketosaveLabel  matlab.ui.control.Label
        SaveOptionListBox         matlab.ui.control.ListBox
        SaveImageButton_2         matlab.ui.control.Button
        Panel_7                   matlab.ui.container.Panel
        Label_4                   matlab.ui.control.Label
        Label_3                   matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        RotatedImage4             matlab.ui.control.Image
        RotatedImage3             matlab.ui.control.Image
        RotatedImage2             matlab.ui.control.Image
        RotatedImage1             matlab.ui.control.Image
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EnchanceImageButton
        function EnchanceImageButtonPushed(app, event)
            x1 = str2double(app.x1TextArea.Value(1));
            x2 = str2double(app.x2TextArea.Value(1));
            y1 = str2double(app.y1TextArea.Value(1));
            y2 = str2double(app.y2TextArea.Value(1));
            image = app.OriginalImage.ImageSource;
            image = imread(image);
            
            % Crop and normalize input image
            newImage = imcrop(image, [x1, y1, x2, y2]);
            newImage = im2double(newImage);
            
            % Convert to YCbCr
            Iycbcr = rgb2ycbcr(newImage);
            % Upsample Y, Cb, and Cr channels using bicubic interpolation
            upscaleFactor = 2;
            Iy_bicubic = imresize(Iycbcr(:,:,1), upscaleFactor * [size(Iycbcr, 1), size(Iycbcr, 2)], 'bicubic');
            Icb_bicubic = imresize(Iycbcr(:,:,2), size(Iy_bicubic), 'bicubic');
            Icr_bicubic = imresize(Iycbcr(:,:,3), size(Iy_bicubic), 'bicubic');
            
            % Prepare bicubic image for comparison
            bicubicImg = ycbcr2rgb(cat(3, Iy_bicubic, Icb_bicubic, Icr_bicubic));
            
            load('trainedVDSR-2024-04-14-18-28-12.mat', 'net');
            
            % Predict super-resolved image
            Iresidual = predict(net, Iy_bicubic);
            Iresidual = double(Iresidual);
            
            % Combine super-resolved Y channel with upscaled CbCr channels
            Isr = Iy_bicubic + Iresidual;
            Isr = imsharpen(Isr, 'Radius', 1, 'Amount', 1.5);  % Sharpen the image to enhance edges
            Ivdsr = ycbcr2rgb(cat(3, Isr, Icb_bicubic, Icr_bicubic));
            
            % Display images
            app.bicubicImage.ImageSource = bicubicImg;
            app.VDSRImage.ImageSource = Ivdsr;
            app.TabGroup.SelectedTab = app.EnhancedImageTab;
            
            % Reference upscaled image for metric comparison
            refImage = imresize(newImage, size(bicubicImg, 1:2));
            
            % Evaluate performance metrics
            bicubicPSNR = psnr(bicubicImg, refImage);
            vdsrPSNR = psnr(Ivdsr, refImage);
            bicubicSSIM = ssim(bicubicImg, refImage);
            vdsrSSIM = ssim(Ivdsr, refImage);
            bicubicNIQE = niqe(bicubicImg);
            vdsrNIQE = niqe(Ivdsr);

        end

        % Callback function
        function ImageNameTextAreaValueChanged(app, event)

        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg';'*.png'; '*.jpeg'});
            app.OriginalImage.ImageSource = file;
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            savedfile = uiputfile({'*.png', '*.jpg'}, 'File Selection', 'enhancedImage.jpg');
            imwrite(app.VDSRImage.ImageSource, savedfile);
        end

        % Button pushed function: RotateImageButton
        function RotateImageButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.RotatedImageTab;
            image = app.VDSRImage.ImageSource;
            
            [input_rows, input_cols] = size(image);

            % Calculate dimensions of the output matrix
            output_cols = input_cols * 2;

            % Create a new matrix with double the number of columns
            output_matrix = zeros(input_rows, output_cols);
            % Perform copying with every other colum
            for col = 1:input_cols
                output_matrix(:, col*2 - 1) = image(:, col); % Copy original column
                output_matrix(:, col*2) = image(:, col); % Copy again to every other column
            end
            % Display the output image
            
            % Calculate the width of the left 1/3rd of the image
            crop_width = floor(output_cols / 3);

            % Define the region of interest (ROI) for cropping
            roi = [1, 1, crop_width, input_rows];

            % Crop the left 1/3rd of the image
            cropped_image = imcrop(output_matrix, roi);
            %imshow(cropped_image);
            cropped_image = cat(3, cropped_image, cropped_image, cropped_image);
            app.RotatedImage1.ImageSource = cropped_image;


            % Calculate dimensions of the output matrix
             output_cols2 = input_cols * 3; % We now want three columns for every original column

            % Create a new matrix with the updated number of columns
            output_matrix2 = zeros(input_rows, output_cols2);

            % Perform copying with two original columns and one copied column
            for col = 1:input_cols
                % Copy original column
                output_matrix2(:, (col-1)*3 + 1) = image(:, col);
                % Copy again to the next column
                output_matrix2(:, (col-1)*3 + 2) = image(:, col);
                % Copy the column before it to the third column
                if col > 1
                    output_matrix2(:, (col-1)*3 + 3) = image(:, col - 1);
                else
                    % If it's the first column, copy the last column instead
                    output_matrix2(:, (col-1)*3 + 3) = image(:, input_cols);
                end
            end
            crop_width2 = floor(output_cols / 2);
            roi2 = [1, 1, crop_width2, input_rows];
            cropped_image = imcrop(output_matrix2, roi2);
            cropped_image = cat(3, cropped_image, cropped_image, cropped_image);
            app.RotatedImage2.ImageSource = cropped_image;


            output_cols3 = input_cols * 3;
            output_matrix3 = zeros(input_rows, output_cols3);

            for col = 2:input_cols
                output_matrix3(:, col*2 - 2) = image(:, col); % Copy original column
                output_matrix3(:, col*2 - 1) = image(:, col); % Copy original column
                output_matrix3(:, col*2) = image(:, col); % Copy again to every other column
            end
            cropped_image = imcrop(output_matrix3, roi);
            cropped_image = cat(3, cropped_image, cropped_image, cropped_image);
            app.RotatedImage3.ImageSource = cropped_image;


            output_cols4 = input_cols * 4; 
            output_matrix4 = zeros(input_rows, output_cols4);
            for col = 1:input_cols
                 output_matrix4(:, (col-1)*4 + 1) = image(:, col);
                 output_matrix4(:, (col-1)*4 + 2) = image(:, col);
                 output_matrix4(:, (col-1)*4 + 3) = image(:, col); % Copy again to the next column
                 output_matrix4(:, (col-1)*4 + 4) = image(:, col); % Copy again to the next column
            end

            crop_width4 = floor(output_cols4 / 3);
            roi2 = [1, 1, crop_width4, input_rows];
            cropped_image = imcrop(output_matrix4, roi2);
            cropped_image = cat(3, cropped_image, cropped_image, cropped_image);
            app.RotatedImage4.ImageSource = cropped_image;
        end

        % Button pushed function: SaveImageButton_2
        function SaveImageButton_2Pushed(app, event)
            savedfile = uiputfile({'*.png', '*.jpg'}, 'File Selection', 'rotatedImage.jpg');
            if app.SaveOptionListBox.Value == 'Image 1'
                imwrite(app.RotatedImage1.ImageSource, savedfile);
            elseif app.SaveOptionListBox.Value == 'Image 2'
                imwrite(app.RotatedImage2.ImageSource, savedfile);
            elseif app.SaveOptionListBox.Value == 'Image 3'
                imwrite(app.RotatedImage3.ImageSource, savedfile);
            elseif app.SaveOptionListBox.Value == 'Image 4'
                imwrite(app.RotatedImage4.ImageSource, savedfile);
            end
        end

        % Button pushed function: SelecttwopointsfromtheimageButton
        function SelecttwopointsfromtheimageButtonPushed(app, event)
            fig = figure;
            imshow(app.OriginalImage.ImageSource);
        
            [x, y] = ginput(2);
            disp(x)
        
            close(fig);

            app.x1TextArea.Value = num2str(min(x));
            app.x2TextArea.Value = num2str(max(x));
            app.y1TextArea.Value = num2str(min(y));
            app.y2TextArea.Value = num2str(max(y));
        end

        % Button pushed function: ShiftUpButton
        function ShiftUpButtonPushed(app, event)
            shiftPixels = app.ShiftPixelsEditField.Value;
            app.y1TextArea.Value = num2str(str2double(app.y1TextArea.Value) - shiftPixels);
            app.y2TextArea.Value = num2str(str2double(app.y2TextArea.Value) - shiftPixels);
            app.EnchanceImageButtonPushed();
        end

        % Button pushed function: ShiftRightButton
        function ShiftRightButtonPushed(app, event)
            shiftPixels = app.ShiftPixelsEditField.Value;
            app.x1TextArea.Value = num2str(str2double(app.x1TextArea.Value) + shiftPixels);
            app.x2TextArea.Value = num2str(str2double(app.x2TextArea.Value) + shiftPixels);
            app.EnchanceImageButtonPushed();
        end

        % Button pushed function: ShiftLeftButton
        function ShiftLeftButtonPushed(app, event)
            shiftPixels = app.ShiftPixelsEditField.Value;
            app.x1TextArea.Value = num2str(str2double(app.x1TextArea.Value) - shiftPixels);
            app.x2TextArea.Value = num2str(str2double(app.x2TextArea.Value) - shiftPixels);
            app.EnchanceImageButtonPushed();
        end

        % Button pushed function: ShiftDownButton
        function ShiftDownButtonPushed(app, event)
            shiftPixels = app.ShiftPixelsEditField.Value;
            app.y1TextArea.Value = num2str(str2double(app.y1TextArea.Value) + shiftPixels);
            app.y2TextArea.Value = num2str(str2double(app.y2TextArea.Value) + shiftPixels);
            app.EnchanceImageButtonPushed();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ImageEnhancementUIFigure = uifigure('Visible', 'off');
            app.ImageEnhancementUIFigure.Name = 'Image Enhancement';
            app.ImageEnhancementUIFigure.Icon = fullfile(pathToMLAPP, 'cameraicon.png');
            app.TabGroup = uitabgroup(app.ImageEnhancementUIFigure);
            app.EnterImageTab = uitab(app.TabGroup);
            app.EnterImageTab.Title = 'Enter Image';
            app.Panel_3 = uipanel(app.EnterImageTab);
            app.Panel_4 = uipanel(app.Panel_3);
            app.y1TextAreaLabel = uilabel(app.Panel_4);
            app.y1TextAreaLabel.Text = 'y1';
            app.y1TextArea = uitextarea(app.Panel_4);
            app.y2TextAreaLabel = uilabel(app.Panel_4);
            app.y2TextAreaLabel.Text = 'y2';
            app.y2TextArea = uitextarea(app.Panel_4);
            app.Panel_2 = uipanel(app.Panel_3);
            app.x2TextAreaLabel = uilabel(app.Panel_2);
            app.x2TextAreaLabel.Text = 'x2';
            app.x2TextArea = uitextarea(app.Panel_2);
            app.x1TextAreaLabel = uilabel(app.Panel_2);
            app.x1TextAreaLabel.Text = 'x1';
            app.x1TextArea = uitextarea(app.Panel_2);
            app.EnchanceImageButton = uibutton(app.Panel_3, 'push');
            app.EnchanceImageButton.ButtonPushedFcn = createCallbackFcn(app, @EnchanceImageButtonPushed, true);
            app.EnchanceImageButton.Text = 'Enchance Image';
            app.SelecttwopointsfromtheimageButton = uibutton(app.Panel_3, 'push');
            app.SelecttwopointsfromtheimageButton.ButtonPushedFcn = createCallbackFcn(app, @SelecttwopointsfromtheimageButtonPushed, true);
            app.SelecttwopointsfromtheimageButton.Tag = 'EnableDragSelectButton';
            app.SelecttwopointsfromtheimageButton.Text = {'Select two points'; 'from the image'};
            app.SelectanimageandenterfourpointsLabel = uilabel(app.Panel_3);
            app.SelectanimageandenterfourpointsLabel.Text = {'Select an image and enter '; 'four coordinates for which '; 'you want to zoom in or'};
            app.Panel_5 = uipanel(app.EnterImageTab);
            app.OriginalImage = uiimage(app.Panel_5);
            app.OriginalImage.ImageSource = fullfile(pathToMLAPP, 'tree.jpg');
            app.BrowseButton = uibutton(app.EnterImageTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.EnhancedImageTab = uitab(app.TabGroup);
            app.EnhancedImageTab.Title = 'Enhanced Image';
            app.Panel_6 = uipanel(app.EnhancedImageTab);
            app.VDSRImage = uiimage(app.Panel_6);
            app.VDSRImage.ImageSource = fullfile(pathToMLAPP, 'box.png');
            app.bicubicImage = uiimage(app.Panel_6);
            app.bicubicImage.ImageSource = fullfile(pathToMLAPP, 'box.png');
            app.SaveImageButton = uibutton(app.EnhancedImageTab, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.RotateImageButton = uibutton(app.EnhancedImageTab, 'push');
            app.RotateImageButton.ButtonPushedFcn = createCallbackFcn(app, @RotateImageButtonPushed, true);
            app.RotateImageButton.Text = 'Rotate Image';
            app.ShiftPixelsEditField = uieditfield(app.EnhancedImageTab, 'numeric');
            app.ShiftUpButton = uibutton(app.EnhancedImageTab, 'push');
            app.ShiftUpButton.ButtonPushedFcn = createCallbackFcn(app, @ShiftUpButtonPushed, true);
            app.ShiftUpButton.Text = 'Shift Up';
            app.ShiftDownButton = uibutton(app.EnhancedImageTab, 'push');
            app.ShiftDownButton.ButtonPushedFcn = createCallbackFcn(app, @ShiftDownButtonPushed, true);
            app.ShiftDownButton.Text = 'Shift Down';
            app.ShiftLeftButton = uibutton(app.EnhancedImageTab, 'push');
            app.ShiftLeftButton.ButtonPushedFcn = createCallbackFcn(app, @ShiftLeftButtonPushed, true);
            app.ShiftLeftButton.Text = 'Shift Left';
            app.ShiftRightButton = uibutton(app.EnhancedImageTab, 'push');
            app.ShiftRightButton.ButtonPushedFcn = createCallbackFcn(app, @ShiftRightButtonPushed, true);
            app.ShiftRightButton.Text = 'Shift Right';
            app.ShiftAmountLabel = uilabel(app.EnhancedImageTab);
            app.ShiftAmountLabel.Text = 'Shift Amount';
            app.RotatedImageTab = uitab(app.TabGroup);
            app.RotatedImageTab.Title = 'Rotated Image';
            app.Panel_7 = uipanel(app.RotatedImageTab);
            app.RotatedImage1 = uiimage(app.Panel_7);
            app.RotatedImage1.ImageSource = fullfile(pathToMLAPP, 'box.png');
            app.RotatedImage2 = uiimage(app.Panel_7);
            app.RotatedImage2.ImageSource = fullfile(pathToMLAPP, 'box.png');
            app.RotatedImage3 = uiimage(app.Panel_7);
            app.RotatedImage3.ImageSource = fullfile(pathToMLAPP, 'box.png');
            app.RotatedImage4 = uiimage(app.Panel_7);
            app.RotatedImage4.ImageSource = fullfile(pathToMLAPP, 'box.png');
            app.Label = uilabel(app.Panel_7);
            app.Label.Text = '1';
            app.Label_2 = uilabel(app.Panel_7);
            app.Label_2.Text = '2';
            app.Label_3 = uilabel(app.Panel_7);
            app.Label_3.Text = '3';
            app.Label_4 = uilabel(app.Panel_7);
            app.Label_4.Text = '4';
            app.SaveImageButton_2 = uibutton(app.RotatedImageTab, 'push');
            app.SaveImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButton_2Pushed, true);
            app.SaveImageButton_2.Text = 'Save Image';
            app.SaveOptionListBox = uilistbox(app.RotatedImageTab);
            app.SaveOptionListBox.Items = {'Image 1', 'Image 2', 'Image 3', 'Image 4'};
            app.SaveOptionListBox.Value = 'Image 1';
            app.WhichimagewouldyouliketosaveLabel = uilabel(app.RotatedImageTab);
            app.WhichimagewouldyouliketosaveLabel.Text = {'Which image '; 'would you like'; 'to save?'};
            % Show the figure after all components are created
            app.ImageEnhancementUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exampleApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageEnhancementUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageEnhancementUIFigure)
        end
    end
end